classdef Formulario < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        tabla                   matlab.ui.control.Table
        EliminarButton          matlab.ui.control.Button
        AnularButton            matlab.ui.control.Button
        ModificarButton         matlab.ui.control.Button
        GuardarButton           matlab.ui.control.Button
        txty                    matlab.ui.control.NumericEditField
        ValorYEditFieldLabel    matlab.ui.control.Label
        txtx                    matlab.ui.control.NumericEditField
        ValorXEditFieldLabel    matlab.ui.control.Label
        FormulariodedatosLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        idDatoG % Description
    end
    
    methods (Access = private)
        
        function mostrar(app)
            app.tabla.Data=consultadato
            app.txtx.Value=0
            app.txty.Value=0
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mostrar(app)
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            valorX=app.txtx.Value
            valorY=app.txty.Value
            insertdato(valorX,valorY,1)
            msgbox("registro Insertado")
            mostrar(app)
        end

        % Selection changed function: tabla
        function tablaSelectionChanged(app, event)
            selection = app.tabla.Selection;
            if ~isempty(selection)

            filaSeleccionada=selection(1);
            app.idDatoG=app.tabla.Data{filaSeleccionada,1}
            datoX=app.tabla.Data{filaSeleccionada,2}
            datoY=app.tabla.Data{filaSeleccionada,3}
            app.txtx.Value=datoX
            app.txty.Value=datoY 

            end
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            datoX=app.txtx.Value
            datoY=app.txty.Value
            actualizardato(app.idDatoG,datoX,datoY,1)
            msgbox("Registro Modificado")
            mostrar(app)
        end

        % Button pushed function: AnularButton
        function AnularButtonPushed(app, event)
            anulardato(app.idDatoG)
            msgbox("Registro Anulado")
            mostrar(app)
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            eliminardato(app.idDatoG)
            msgbox("Registro Eliminado")
            mostrar(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FormulariodedatosLabel = uilabel(app.UIFigure);
            app.FormulariodedatosLabel.Text = 'Formulario de datos';
            app.ValorXEditFieldLabel = uilabel(app.UIFigure);
            app.ValorXEditFieldLabel.Text = 'Valor X';
            app.txtx = uieditfield(app.UIFigure, 'numeric');
            app.ValorYEditFieldLabel = uilabel(app.UIFigure);
            app.ValorYEditFieldLabel.Text = 'Valor Y';
            app.txty = uieditfield(app.UIFigure, 'numeric');
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ModificarButton = uibutton(app.UIFigure, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Text = 'Modificar';
            app.AnularButton = uibutton(app.UIFigure, 'push');
            app.AnularButton.ButtonPushedFcn = createCallbackFcn(app, @AnularButtonPushed, true);
            app.AnularButton.Text = 'Anular';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'id'; 'Valor X'; 'Valor Y'};
            app.tabla.RowName = {};
            app.tabla.SelectionChangedFcn = createCallbackFcn(app, @tablaSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Formulario

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end