classdef form2Y2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MenuButton                      matlab.ui.control.Button
        GraficaButton                   matlab.ui.control.Button
        tabla                           matlab.ui.control.Table
        CalcularButton                  matlab.ui.control.Button
        txtr2                           matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        txt4                            matlab.ui.control.NumericEditField
        Y2EditFieldLabel                matlab.ui.control.Label
        txt3                            matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        txtr1                           matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        txt2                            matlab.ui.control.NumericEditField
        Y1EditFieldLabel                matlab.ui.control.Label
        txt1                            matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        Calculadoradeecuaciones22Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            num1=app.txt1.Value
            num2=app.txt2.Value
            r1=app.txtr1.Value
            num3=app.txt3.Value
            num4=app.txt4.Value
            r2=app.txtr2.Value
            app.tabla.Data=ecuacion2y2(num1,num2,r1,num3,num4,r2)

        end

        % Button pushed function: GraficaButton
        function GraficaButtonPushed(app, event)
            num1=app.txt1.Value
            num2=app.txt2.Value
            r1=app.txtr1.Value
            num3=app.txt3.Value
            num4=app.txt4.Value
            r2=app.txtr2.Value
            grafica2y2(num1,num2,r1,num3,num4,r2)
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            delete(app)
            Menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Calculadoradeecuaciones22Label = uilabel(app.UIFigure);
            app.Calculadoradeecuaciones22Label.Text = 'Calculadora de ecuaciones 2*2';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.txt1 = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.txt2 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '=';
            app.txtr1 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.txt3 = uieditfield(app.UIFigure, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.txt4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '=';
            app.txtr2 = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.tabla.RowName = {};
            app.GraficaButton = uibutton(app.UIFigure, 'push');
            app.GraficaButton.ButtonPushedFcn = createCallbackFcn(app, @GraficaButtonPushed, true);
            app.GraficaButton.Text = 'Grafica';
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = 'Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = form2Y2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end