classdef form3y3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        MenuButton      matlab.ui.control.Button
        txtr3           matlab.ui.control.NumericEditField
        Label_3         matlab.ui.control.Label
        txt9            matlab.ui.control.NumericEditField
        z3Label         matlab.ui.control.Label
        txt8            matlab.ui.control.NumericEditField
        y3Label         matlab.ui.control.Label
        txt7            matlab.ui.control.NumericEditField
        x3Label         matlab.ui.control.Label
        txtr2           matlab.ui.control.NumericEditField
        Label_2         matlab.ui.control.Label
        txt6            matlab.ui.control.NumericEditField
        z2Label         matlab.ui.control.Label
        txt5            matlab.ui.control.NumericEditField
        y2Label         matlab.ui.control.Label
        txt4            matlab.ui.control.NumericEditField
        x2Label         matlab.ui.control.Label
        txtr1           matlab.ui.control.NumericEditField
        Label           matlab.ui.control.Label
        txt3            matlab.ui.control.NumericEditField
        z1Label         matlab.ui.control.Label
        txt2            matlab.ui.control.NumericEditField
        y1Label         matlab.ui.control.Label
        txt1            matlab.ui.control.NumericEditField
        x1Label         matlab.ui.control.Label
        tabla           matlab.ui.control.Table
        Graficar        matlab.ui.control.Button
        Resolver        matlab.ui.control.Button
        Grafica33Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Resolver
        function ResolverButtonPushed(app, event)
            n1=app.txt1.Value
            n2=app.txt2.Value
            n3=app.txt3.Value
            r1=app.txtr1.Value

            n4=app.txt4.Value
            n5=app.txt5.Value
            n6=app.txt6.Value
            r2=app.txtr2.Value

            n7=app.txt7.Value
            n8=app.txt8.Value
            n9=app.txt9.Value
            r3=app.txtr3.Value
            
            app.tabla.Data=funcion3y3(n1,n2,n3,r1,n4,n5,n6,r2,n7,n8,n9,r3)
        end

        % Button pushed function: Graficar
        function GraficarButtonPushed(app, event)
            n1=app.txt1.Value
            n2=app.txt2.Value
            n3=app.txt3.Value
            r1=app.txtr1.Value

            n4=app.txt4.Value
            n5=app.txt5.Value
            n6=app.txt6.Value
            r2=app.txtr2.Value

            n7=app.txt7.Value
            n8=app.txt8.Value
            n9=app.txt9.Value
            r3=app.txtr3.Value

            grafica3y3(n1,n2,n3,r1,n4,n5,n6,r2,n7,n8,n9,r3)
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            delete(app)
            Menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica33Label = uilabel(app.UIFigure);
            app.Grafica33Label.Text = 'Grafica 3*3';
            app.Resolver = uibutton(app.UIFigure, 'push');
            app.Resolver.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.Resolver.Text = 'Resolver';
            app.Graficar = uibutton(app.UIFigure, 'push');
            app.Graficar.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.Graficar.Text = 'Graficar';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.tabla.RowName = {};
            app.x1Label = uilabel(app.UIFigure);
            app.x1Label.Text = 'x1';
            app.txt1 = uieditfield(app.UIFigure, 'numeric');
            app.y1Label = uilabel(app.UIFigure);
            app.y1Label.Text = 'y1';
            app.txt2 = uieditfield(app.UIFigure, 'numeric');
            app.z1Label = uilabel(app.UIFigure);
            app.z1Label.Text = 'z1';
            app.txt3 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.txtr1 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = 'x2';
            app.txt4 = uieditfield(app.UIFigure, 'numeric');
            app.y2Label = uilabel(app.UIFigure);
            app.y2Label.Text = 'y2';
            app.txt5 = uieditfield(app.UIFigure, 'numeric');
            app.z2Label = uilabel(app.UIFigure);
            app.z2Label.Text = 'z2';
            app.txt6 = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.txtr2 = uieditfield(app.UIFigure, 'numeric');
            app.x3Label = uilabel(app.UIFigure);
            app.x3Label.Text = 'x3';
            app.txt7 = uieditfield(app.UIFigure, 'numeric');
            app.y3Label = uilabel(app.UIFigure);
            app.y3Label.Text = 'y3';
            app.txt8 = uieditfield(app.UIFigure, 'numeric');
            app.z3Label = uilabel(app.UIFigure);
            app.z3Label.Text = 'z3';
            app.txt9 = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.txtr3 = uieditfield(app.UIFigure, 'numeric');
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = 'Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = form3y3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end